classdef Ares_DAQ_Calibration_2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LC_T_file                       matlab.ui.control.EditField
        FileorlinEditField_13Label      matlab.ui.control.Label
        T_c_file                        matlab.ui.control.EditField
        FileorlinEditField_12Label      matlab.ui.control.Label
        T_fu_file                       matlab.ui.control.EditField
        FileorlinEditField_11Label      matlab.ui.control.Label
        T_lox_file                      matlab.ui.control.EditField
        FileorlinEditField_10Label      matlab.ui.control.Label
        T_pr_file                       matlab.ui.control.EditField
        FileorlinEditField_9Label       matlab.ui.control.Label
        p_c_file                        matlab.ui.control.EditField
        FileorlinEditField_8Label       matlab.ui.control.Label
        p_pneu_file                     matlab.ui.control.EditField
        FileorlinEditField_7Label       matlab.ui.control.Label
        p_fuman_file                    matlab.ui.control.EditField
        FileorlinEditField_6Label       matlab.ui.control.Label
        p_loxman_file                   matlab.ui.control.EditField
        FileorlinEditField_5Label       matlab.ui.control.Label
        p_ro_file                       matlab.ui.control.EditField
        FileorlinEditField_4Label       matlab.ui.control.Label
        p_fu_file                       matlab.ui.control.EditField
        FileorlinEditField_3Label       matlab.ui.control.Label
        p_lox_file                      matlab.ui.control.EditField
        FileorlinEditField_2Label       matlab.ui.control.Label
        p_pr_file                       matlab.ui.control.EditField
        FileorlinEditFieldLabel         matlab.ui.control.Label
        p_ro_ch                         matlab.ui.control.DropDown
        ChannelDropDown_4Label_2        matlab.ui.control.Label
        p_ro_b                          matlab.ui.control.NumericEditField
        offsetEditField_4Label_2        matlab.ui.control.Label
        p_ro_a                          matlab.ui.control.NumericEditField
        slopeEditField_4Label_2         matlab.ui.control.Label
        RegOutletPTLabel                matlab.ui.control.Label
        LC_T_ch                         matlab.ui.control.DropDown
        ChannelDropDown_12Label         matlab.ui.control.Label
        T_c_ch                          matlab.ui.control.DropDown
        ChannelDropDown_11Label         matlab.ui.control.Label
        T_fu_ch                         matlab.ui.control.DropDown
        ChannelDropDown_10Label         matlab.ui.control.Label
        T_lox_ch                        matlab.ui.control.DropDown
        ChannelDropDown_9Label          matlab.ui.control.Label
        T_pr_ch                         matlab.ui.control.DropDown
        ChannelDropDown_8Label          matlab.ui.control.Label
        p_pneu_ch                       matlab.ui.control.DropDown
        ChannelDropDown_7Label          matlab.ui.control.Label
        p_fuman_ch                      matlab.ui.control.DropDown
        ChannelDropDown_6Label          matlab.ui.control.Label
        p_loxman_ch                     matlab.ui.control.DropDown
        ChannelDropDown_5Label          matlab.ui.control.Label
        p_c_ch                          matlab.ui.control.DropDown
        ChannelDropDown_4Label          matlab.ui.control.Label
        p_fu_ch                         matlab.ui.control.DropDown
        ChannelDropDown_3Label          matlab.ui.control.Label
        p_lox_ch                        matlab.ui.control.DropDown
        ChannelDropDown_2Label          matlab.ui.control.Label
        p_pr_ch                         matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ApplyandCloseButton             matlab.ui.control.Button
        SaveFileButton                  matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        LC_T_b                          matlab.ui.control.NumericEditField
        offsetEditField_12Label         matlab.ui.control.Label
        LC_T_a                          matlab.ui.control.NumericEditField
        slopeEditField_12Label          matlab.ui.control.Label
        ThrustLCLabel                   matlab.ui.control.Label
        LoadCellCalibrationValuesLabel  matlab.ui.control.Label
        T_c_b                           matlab.ui.control.NumericEditField
        offsetEditField_11Label         matlab.ui.control.Label
        T_c_a                           matlab.ui.control.NumericEditField
        slopeEditField_11Label          matlab.ui.control.Label
        CombustionChamberTCLabel        matlab.ui.control.Label
        T_fu_b                          matlab.ui.control.NumericEditField
        offsetEditField_10Label         matlab.ui.control.Label
        T_fu_a                          matlab.ui.control.NumericEditField
        slopeEditField_10Label          matlab.ui.control.Label
        T_lox_b                         matlab.ui.control.NumericEditField
        offsetEditField_9Label          matlab.ui.control.Label
        T_lox_a                         matlab.ui.control.NumericEditField
        slopeEditField_9Label           matlab.ui.control.Label
        T_pr_b                          matlab.ui.control.NumericEditField
        offsetEditField_8Label          matlab.ui.control.Label
        T_pr_a                          matlab.ui.control.NumericEditField
        slopeEditField_8Label           matlab.ui.control.Label
        FuelTankTCLabel                 matlab.ui.control.Label
        LOXTankTCLabel                  matlab.ui.control.Label
        PressurantTankTCLabel           matlab.ui.control.Label
        ThermocoupleCalibrationValuesLabel  matlab.ui.control.Label
        p_pneu_b                        matlab.ui.control.NumericEditField
        offsetEditField_7Label          matlab.ui.control.Label
        p_pneu_a                        matlab.ui.control.NumericEditField
        slopeEditField_7Label           matlab.ui.control.Label
        PneumaticsPTLabel               matlab.ui.control.Label
        p_fuman_b                       matlab.ui.control.NumericEditField
        offsetEditField_6Label          matlab.ui.control.Label
        p_fuman_a                       matlab.ui.control.NumericEditField
        slopeEditField_6Label           matlab.ui.control.Label
        p_loxman_b                      matlab.ui.control.NumericEditField
        offsetEditField_5Label          matlab.ui.control.Label
        p_loxman_a                      matlab.ui.control.NumericEditField
        slopeEditField_5Label           matlab.ui.control.Label
        p_c_b                           matlab.ui.control.NumericEditField
        offsetEditField_4Label          matlab.ui.control.Label
        p_c_a                           matlab.ui.control.NumericEditField
        slopeEditField_4Label           matlab.ui.control.Label
        FuelManifoldPTLabel             matlab.ui.control.Label
        LOXManifoldPTLabel              matlab.ui.control.Label
        CombustionChamberPTLabel        matlab.ui.control.Label
        p_fu_b                          matlab.ui.control.NumericEditField
        offsetEditField_3Label          matlab.ui.control.Label
        p_fu_a                          matlab.ui.control.NumericEditField
        slopeEditField_3Label           matlab.ui.control.Label
        FuelTankPTLabel                 matlab.ui.control.Label
        p_lox_b                         matlab.ui.control.NumericEditField
        offsetEditField_2Label          matlab.ui.control.Label
        p_lox_a                         matlab.ui.control.NumericEditField
        slopeEditField_2Label           matlab.ui.control.Label
        LOXTankPTLabel                  matlab.ui.control.Label
        PressurantTankPTLabel           matlab.ui.control.Label
        PressureTransducerCalibrationValuesLabel  matlab.ui.control.Label
        p_pr_b                          matlab.ui.control.NumericEditField
        offsetEditFieldLabel            matlab.ui.control.Label
        p_pr_a                          matlab.ui.control.NumericEditField
        slopeEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ParentGUI % Description
        SensorNames = {'Pressurant Tank PT','LOX Tank PT','Fuel Tank PT',...
            'Regulator Outlet','LOX Manifold PT','Fuel Manifold PT',...
            'Pneumatics PT','Combustion Chamber PT',...
            'Pressurant Tank TC','LOX Tank TC','Fuel Tank TC',...
            'Combustion Chamber TC','Thrust LC'};
        N_sensors = 13;
        CaliVals
        ChanVals
        CaliFiles
        CaliMats
        max_pressure = 10000;
    end
    
    methods (Access = private)
        
        function InputsValid = CheckInputs(app,ChanStrArr)
            for i = 1:1:length(ChanStrArr)
                if strcmp(ChanStrArr{i},'Select')
                     errordlg('All sensors must have a specified channel','Input Error')
                     InputsValid = 0;
                     return
                end
                for j = i+1:1:length(ChanStrArr)
                    if strcmp(ChanStrArr{i},ChanStrArr{j})
                        errordlg(['All sensors must have a unique specified channel. Check ',...
                            app.SensorNames{i},' and ',app.SensorNames{j},'.'],'Input Error');
                        InputsValid = 0;
                        return
                    end
                end
            end
            InputsValid = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, CaliArr, ChanArr, FilesArr)
            app.ParentGUI = mainapp;
            app.ParentGUI.Cali_open = 1;
            app.CaliVals = {app.p_pr_a,app.p_pr_b;
                app.p_lox_a,app.p_lox_b;
                app.p_fu_a,app.p_fu_b;
                app.p_ro_a,app.p_ro_b;
                app.p_loxman_a,app.p_loxman_b;
                app.p_fuman_a,app.p_fuman_b;
                app.p_pneu_a,app.p_pneu_b;
                app.p_c_a,app.p_c_b;
                app.T_pr_a,app.T_pr_b;
                app.T_lox_a,app.T_lox_b;
                app.T_fu_a,app.T_fu_b;
                app.T_c_a,app.T_c_b;
                app.LC_T_a,app.LC_T_b};
            
            app.ChanVals = {app.p_pr_ch;
                app.p_lox_ch;
                app.p_fu_ch;
                app.p_ro_ch;
                app.p_loxman_ch;
                app.p_fuman_ch;
                app.p_pneu_ch;
                app.p_c_ch;
                app.T_pr_ch;
                app.T_lox_ch;
                app.T_fu_ch;
                app.T_c_ch;
                app.LC_T_ch};
            
            app.CaliFiles = {app.p_pr_file;
                app.p_lox_file;
                app.p_fu_file;
                app.p_ro_file;
                app.p_loxman_file;
                app.p_fuman_file;
                app.p_pneu_file;
                app.p_c_file;
                app.T_pr_file;
                app.T_lox_file;
                app.T_fu_file;
                app.T_c_file;
                app.LC_T_file};
            
            if length(app.CaliVals) ~= length(app.ChanVals), error('CaliVals and ChanVals sizes do not match'); end
            if length(app.CaliVals) ~= length(app.CaliFiles), error('CaliVals and CaliFiles sizes do not match'); end
            
            for c = 1:1:size(app.CaliVals,2)
                for r = 1:1:size(app.CaliVals,1)
                    app.CaliVals{r,c}.Value = CaliArr(r,c);
                end
            end
            
            for l = 1:1:length(app.ChanVals)
                if isnan(ChanArr(l))
                    app.ChanVals{l}.Value = 'Select';
                else
                    app.ChanVals{l}.Value = sprintf('%d',ChanArr(l));
                end
            end
            
            for r = 1:1:length(app.CaliFiles)
                app.CaliFiles{r}.Value = FilesArr{r};
            end
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            for c = 1:1:size(app.CaliVals,2)
                for r = 1:1:size(app.CaliVals,1)
                    CaliArr(r,c) = app.CaliVals{r,c}.Value;
                end
            end
            
            
            for r = 1:1:length(app.CaliVals)
                if ~strcmp(app.CaliFiles{r}.Value,'lin')
                    try
                        load(app.CaliFiles{r}.Value)
                    catch
                        errordlg(['File name not specified or could not find file name '''...
                            app.CaliFiles{r}.Value ''' for sensor ' app.SensorNames{r} '.'],'Invalid File')
                        return
                    end
                    if exist('values','var') == 0 || exist('pressures','var') == 0
                        errordlg(['Arrays in ''' app.CaliFiles{r}.Value ''' not named correctly. Arrays should be names ''values'' and ''pressures''.'])
                        return
                    end
                end
            end
                        
            for l = 1:1:length(app.ChanVals)
                ChanStrArr{l} = app.ChanVals{l}.Value;
            end
            
            for r = 1:1:length(app.CaliFiles)
                FilesArr{r} = app.CaliFiles{r}.Value;
            end
            
            if CheckInputs(app,ChanStrArr)
                ChanArr = str2double(ChanStrArr);
                [FileName,PathName] = uiputfile('*.mat');
                save([PathName,FileName],"CaliArr","ChanArr","FilesArr")
            else
                return
            end

        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [fileName,pathName] = uigetfile('*mat');
            if fileName == 0
                return;
            else
                try
                    load([pathName,fileName],'-mat');
                catch
                    errordlg('There was a problem loading the calibration file','Loading Error')
                    return;
                end
                
                for c = 1:1:size(app.CaliVals,2)
                    for r = 1:1:size(app.CaliVals,1)
                        app.CaliVals{r,c}.Value = CaliArr(r,c);
                    end
                end
                
                for l = 1:1:length(app.ChanVals)
                    if isnan(ChanArr(l))
                        app.ChanVals{l}.Value = 'Select';
                    else
                        app.ChanVals{l}.Value = sprintf('%d',ChanArr(l));
                    end
                end
                
                for r = 1:1:length(app.CaliFiles)
                    app.CaliFiles{r}.Value = FilesArr{r};
                end
            end
        end

        % Button pushed function: ApplyandCloseButton
        function ApplyandCloseButtonPushed(app, event)
            for c = 1:1:size(app.CaliVals,2)
                for r = 1:1:size(app.CaliVals,1)
                    CaliArr(r,c) = app.CaliVals{r,c}.Value;
                end
            end
            
            for r = 1:1:length(app.CaliVals)
                if strcmp(app.CaliFiles{r}.Value,'lin')
                    app.CaliMats{r,1} = [app.CaliVals{r,2}.Value; app.CaliVals{r,1}.Value*app.max_pressure + app.CaliVals{r,2}.Value];
                    app.CaliMats{r,2} = [0; app.max_pressure];
                else
                    try
                        load(app.CaliFiles{r}.Value)
                    catch
                        errordlg(['File name not specified or could not find file name '''...
                            app.CaliFiles{r}.Value ''' for sensor ' app.SensorNames{r} '.'],'Invalid File')
                        return
                    end
                    
                    if exist('values','var') == 0 || exist('pressures','var') == 0
                        errordlg(['Arrays in ''' app.CaliFiles{r}.Value ''' not named correctly. Arrays should be names ''values'' and ''pressures''.'])
                        return
                    else
                        app.CaliMats{r,1} = values;
                        app.CaliMats{r,2} = pressures;
                        clear values pressures
                    end
                end
            end

            for l = 1:1:length(app.ChanVals)
                ChanStrArr{l} = app.ChanVals{l}.Value;
            end
            
            for r = 1:1:length(app.CaliFiles)
                FilesArr{r} = app.CaliFiles{r}.Value;
            end
            
            CaliMats = app.CaliMats;
            
            if CheckInputs(app,ChanStrArr)
                ChanArr = str2double(ChanStrArr);
                UpdateCali(app.ParentGUI,CaliArr,ChanArr,FilesArr,CaliMats)
                app.ParentGUI.EnterCalibrationValuesButton.Enable = 'on';
                delete(app)
            else
                return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('app.ParentGUI','class') == 1
                app.ParentGUI.EnterCalibrationValuesButton.Enable = 'on';
                app.ParentGUI.Cali_open = 0;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.slopeEditFieldLabel = uilabel(app.UIFigure);
            app.slopeEditFieldLabel.Text = 'slope';
            app.p_pr_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditFieldLabel = uilabel(app.UIFigure);
            app.offsetEditFieldLabel.Text = 'offset';
            app.p_pr_b = uieditfield(app.UIFigure, 'numeric');
            app.PressureTransducerCalibrationValuesLabel = uilabel(app.UIFigure);
            app.PressureTransducerCalibrationValuesLabel.Text = 'Pressure Transducer Calibration Values';
            app.PressurantTankPTLabel = uilabel(app.UIFigure);
            app.PressurantTankPTLabel.Text = 'Pressurant Tank PT';
            app.LOXTankPTLabel = uilabel(app.UIFigure);
            app.LOXTankPTLabel.Text = 'LOX Tank PT';
            app.slopeEditField_2Label = uilabel(app.UIFigure);
            app.slopeEditField_2Label.Text = 'slope';
            app.p_lox_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_2Label = uilabel(app.UIFigure);
            app.offsetEditField_2Label.Text = 'offset';
            app.p_lox_b = uieditfield(app.UIFigure, 'numeric');
            app.FuelTankPTLabel = uilabel(app.UIFigure);
            app.FuelTankPTLabel.Text = 'Fuel Tank PT';
            app.slopeEditField_3Label = uilabel(app.UIFigure);
            app.slopeEditField_3Label.Text = 'slope';
            app.p_fu_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_3Label = uilabel(app.UIFigure);
            app.offsetEditField_3Label.Text = 'offset';
            app.p_fu_b = uieditfield(app.UIFigure, 'numeric');
            app.CombustionChamberPTLabel = uilabel(app.UIFigure);
            app.CombustionChamberPTLabel.Text = 'Combustion Chamber PT';
            app.LOXManifoldPTLabel = uilabel(app.UIFigure);
            app.LOXManifoldPTLabel.Text = 'LOX Manifold PT';
            app.FuelManifoldPTLabel = uilabel(app.UIFigure);
            app.FuelManifoldPTLabel.Text = 'Fuel Manifold PT';
            app.slopeEditField_4Label = uilabel(app.UIFigure);
            app.slopeEditField_4Label.Text = 'slope';
            app.p_c_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_4Label = uilabel(app.UIFigure);
            app.offsetEditField_4Label.Text = 'offset';
            app.p_c_b = uieditfield(app.UIFigure, 'numeric');
            app.slopeEditField_5Label = uilabel(app.UIFigure);
            app.slopeEditField_5Label.Text = 'slope';
            app.p_loxman_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_5Label = uilabel(app.UIFigure);
            app.offsetEditField_5Label.Text = 'offset';
            app.p_loxman_b = uieditfield(app.UIFigure, 'numeric');
            app.slopeEditField_6Label = uilabel(app.UIFigure);
            app.slopeEditField_6Label.Text = 'slope';
            app.p_fuman_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_6Label = uilabel(app.UIFigure);
            app.offsetEditField_6Label.Text = 'offset';
            app.p_fuman_b = uieditfield(app.UIFigure, 'numeric');
            app.PneumaticsPTLabel = uilabel(app.UIFigure);
            app.PneumaticsPTLabel.Text = 'Pneumatics PT';
            app.slopeEditField_7Label = uilabel(app.UIFigure);
            app.slopeEditField_7Label.Text = 'slope';
            app.p_pneu_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_7Label = uilabel(app.UIFigure);
            app.offsetEditField_7Label.Text = 'offset';
            app.p_pneu_b = uieditfield(app.UIFigure, 'numeric');
            app.ThermocoupleCalibrationValuesLabel = uilabel(app.UIFigure);
            app.ThermocoupleCalibrationValuesLabel.Text = 'Thermocouple Calibration Values';
            app.PressurantTankTCLabel = uilabel(app.UIFigure);
            app.PressurantTankTCLabel.Text = 'Pressurant Tank TC';
            app.LOXTankTCLabel = uilabel(app.UIFigure);
            app.LOXTankTCLabel.Text = 'LOX Tank TC';
            app.FuelTankTCLabel = uilabel(app.UIFigure);
            app.FuelTankTCLabel.Text = 'Fuel Tank TC';
            app.slopeEditField_8Label = uilabel(app.UIFigure);
            app.slopeEditField_8Label.Text = 'slope';
            app.T_pr_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_8Label = uilabel(app.UIFigure);
            app.offsetEditField_8Label.Text = 'offset';
            app.T_pr_b = uieditfield(app.UIFigure, 'numeric');
            app.slopeEditField_9Label = uilabel(app.UIFigure);
            app.slopeEditField_9Label.Text = 'slope';
            app.T_lox_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_9Label = uilabel(app.UIFigure);
            app.offsetEditField_9Label.Text = 'offset';
            app.T_lox_b = uieditfield(app.UIFigure, 'numeric');
            app.slopeEditField_10Label = uilabel(app.UIFigure);
            app.slopeEditField_10Label.Text = 'slope';
            app.T_fu_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_10Label = uilabel(app.UIFigure);
            app.offsetEditField_10Label.Text = 'offset';
            app.T_fu_b = uieditfield(app.UIFigure, 'numeric');
            app.CombustionChamberTCLabel = uilabel(app.UIFigure);
            app.CombustionChamberTCLabel.Text = 'Combustion Chamber TC';
            app.slopeEditField_11Label = uilabel(app.UIFigure);
            app.slopeEditField_11Label.Text = 'slope';
            app.T_c_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_11Label = uilabel(app.UIFigure);
            app.offsetEditField_11Label.Text = 'offset';
            app.T_c_b = uieditfield(app.UIFigure, 'numeric');
            app.LoadCellCalibrationValuesLabel = uilabel(app.UIFigure);
            app.LoadCellCalibrationValuesLabel.Text = 'Load Cell Calibration Values';
            app.ThrustLCLabel = uilabel(app.UIFigure);
            app.ThrustLCLabel.Text = 'Thrust LC';
            app.slopeEditField_12Label = uilabel(app.UIFigure);
            app.slopeEditField_12Label.Text = 'slope';
            app.LC_T_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_12Label = uilabel(app.UIFigure);
            app.offsetEditField_12Label.Text = 'offset';
            app.LC_T_b = uieditfield(app.UIFigure, 'numeric');
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.ApplyandCloseButton = uibutton(app.UIFigure, 'push');
            app.ApplyandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyandCloseButtonPushed, true);
            app.ApplyandCloseButton.Text = 'Apply and Close';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.p_pr_ch = uidropdown(app.UIFigure);
            app.p_pr_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_pr_ch.Value = '1';
            app.ChannelDropDown_2Label = uilabel(app.UIFigure);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.p_lox_ch = uidropdown(app.UIFigure);
            app.p_lox_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_lox_ch.Value = '2';
            app.ChannelDropDown_3Label = uilabel(app.UIFigure);
            app.ChannelDropDown_3Label.Text = 'Channel';
            app.p_fu_ch = uidropdown(app.UIFigure);
            app.p_fu_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_fu_ch.Value = '3';
            app.ChannelDropDown_4Label = uilabel(app.UIFigure);
            app.ChannelDropDown_4Label.Text = 'Channel';
            app.p_c_ch = uidropdown(app.UIFigure);
            app.p_c_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_c_ch.Value = '8';
            app.ChannelDropDown_5Label = uilabel(app.UIFigure);
            app.ChannelDropDown_5Label.Text = 'Channel';
            app.p_loxman_ch = uidropdown(app.UIFigure);
            app.p_loxman_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_loxman_ch.Value = '5';
            app.ChannelDropDown_6Label = uilabel(app.UIFigure);
            app.ChannelDropDown_6Label.Text = 'Channel';
            app.p_fuman_ch = uidropdown(app.UIFigure);
            app.p_fuman_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_fuman_ch.Value = '6';
            app.ChannelDropDown_7Label = uilabel(app.UIFigure);
            app.ChannelDropDown_7Label.Text = 'Channel';
            app.p_pneu_ch = uidropdown(app.UIFigure);
            app.p_pneu_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_pneu_ch.Value = '7';
            app.ChannelDropDown_8Label = uilabel(app.UIFigure);
            app.ChannelDropDown_8Label.Text = 'Channel';
            app.T_pr_ch = uidropdown(app.UIFigure);
            app.T_pr_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.T_pr_ch.Value = '9';
            app.ChannelDropDown_9Label = uilabel(app.UIFigure);
            app.ChannelDropDown_9Label.Text = 'Channel';
            app.T_lox_ch = uidropdown(app.UIFigure);
            app.T_lox_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.T_lox_ch.Value = '10';
            app.ChannelDropDown_10Label = uilabel(app.UIFigure);
            app.ChannelDropDown_10Label.Text = 'Channel';
            app.T_fu_ch = uidropdown(app.UIFigure);
            app.T_fu_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.T_fu_ch.Value = '11';
            app.ChannelDropDown_11Label = uilabel(app.UIFigure);
            app.ChannelDropDown_11Label.Text = 'Channel';
            app.T_c_ch = uidropdown(app.UIFigure);
            app.T_c_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.T_c_ch.Value = '12';
            app.ChannelDropDown_12Label = uilabel(app.UIFigure);
            app.ChannelDropDown_12Label.Text = 'Channel';
            app.LC_T_ch = uidropdown(app.UIFigure);
            app.LC_T_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.LC_T_ch.Value = '13';
            app.RegOutletPTLabel = uilabel(app.UIFigure);
            app.RegOutletPTLabel.Text = 'Reg Outlet PT';
            app.slopeEditField_4Label_2 = uilabel(app.UIFigure);
            app.slopeEditField_4Label_2.Text = 'slope';
            app.p_ro_a = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField_4Label_2 = uilabel(app.UIFigure);
            app.offsetEditField_4Label_2.Text = 'offset';
            app.p_ro_b = uieditfield(app.UIFigure, 'numeric');
            app.ChannelDropDown_4Label_2 = uilabel(app.UIFigure);
            app.ChannelDropDown_4Label_2.Text = 'Channel';
            app.p_ro_ch = uidropdown(app.UIFigure);
            app.p_ro_ch.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.p_ro_ch.Value = '4';
            app.FileorlinEditFieldLabel = uilabel(app.UIFigure);
            app.FileorlinEditFieldLabel.Text = 'File or "lin"';
            app.p_pr_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_2Label = uilabel(app.UIFigure);
            app.FileorlinEditField_2Label.Text = 'File or "lin"';
            app.p_lox_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_3Label = uilabel(app.UIFigure);
            app.FileorlinEditField_3Label.Text = 'File or "lin"';
            app.p_fu_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_4Label = uilabel(app.UIFigure);
            app.FileorlinEditField_4Label.Text = 'File or "lin"';
            app.p_ro_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_5Label = uilabel(app.UIFigure);
            app.FileorlinEditField_5Label.Text = 'File or "lin"';
            app.p_loxman_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_6Label = uilabel(app.UIFigure);
            app.FileorlinEditField_6Label.Text = 'File or "lin"';
            app.p_fuman_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_7Label = uilabel(app.UIFigure);
            app.FileorlinEditField_7Label.Text = 'File or "lin"';
            app.p_pneu_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_8Label = uilabel(app.UIFigure);
            app.FileorlinEditField_8Label.Text = 'File or "lin"';
            app.p_c_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_9Label = uilabel(app.UIFigure);
            app.FileorlinEditField_9Label.Text = 'File or "lin"';
            app.T_pr_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_10Label = uilabel(app.UIFigure);
            app.FileorlinEditField_10Label.Text = 'File or "lin"';
            app.T_lox_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_11Label = uilabel(app.UIFigure);
            app.FileorlinEditField_11Label.Text = 'File or "lin"';
            app.T_fu_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_12Label = uilabel(app.UIFigure);
            app.FileorlinEditField_12Label.Text = 'File or "lin"';
            app.T_c_file = uieditfield(app.UIFigure, 'text');
            app.FileorlinEditField_13Label = uilabel(app.UIFigure);
            app.FileorlinEditField_13Label.Text = 'File or "lin"';
            app.LC_T_file = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ares_DAQ_Calibration_2021(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end