classdef Ares_DAQ_GUI_2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        p_lc_hiylim                    matlab.ui.control.NumericEditField
        LoadCellylimitsLabel           matlab.ui.control.Label
        p_lc_loylim                    matlab.ui.control.NumericEditField
        p_c_hiylim                     matlab.ui.control.NumericEditField
        ChamberPressureylimitsLabel    matlab.ui.control.Label
        p_c_loylim                     matlab.ui.control.NumericEditField
        p_fuman_val                    matlab.ui.control.NumericEditField
        p_loxman_val                   matlab.ui.control.NumericEditField
        p_c_val                        matlab.ui.control.NumericEditField
        p_lc_val                       matlab.ui.control.NumericEditField
        p_ro_hiylim                    matlab.ui.control.NumericEditField
        ylimitsLabel_4                 matlab.ui.control.Label
        p_ro_loylim                    matlab.ui.control.NumericEditField
        p_ro_val                       matlab.ui.control.NumericEditField
        RefreshLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        ProjectAres20202021PropulsionSystemDAQLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        ControllerInputEditField       matlab.ui.control.EditField
        ControllerInputEditFieldLabel  matlab.ui.control.Label
        p_fu_hiylim                    matlab.ui.control.NumericEditField
        ylimitsLabel_3                 matlab.ui.control.Label
        p_fu_loylim                    matlab.ui.control.NumericEditField
        p_lox_hiylim                   matlab.ui.control.NumericEditField
        ylimitsLabel_2                 matlab.ui.control.Label
        p_lox_loylim                   matlab.ui.control.NumericEditField
        p_pr_hiylim                    matlab.ui.control.NumericEditField
        ylimitsLabel                   matlab.ui.control.Label
        p_pr_loylim                    matlab.ui.control.NumericEditField
        p_fu_val                       matlab.ui.control.NumericEditField
        p_lox_val                      matlab.ui.control.NumericEditField
        p_pr_val                       matlab.ui.control.NumericEditField
        secLabel                       matlab.ui.control.Label
        TimeIntervaltoPlotEditField    matlab.ui.control.NumericEditField
        TimeIntervaltoPlotEditFieldLabel  matlab.ui.control.Label
        EnterCalibrationValuesButton   matlab.ui.control.Button
        RRLamp                         matlab.ui.control.Lamp
        RunningandRecordingLabel       matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        p_ro                           matlab.ui.control.UIAxes
        p_pneu                         matlab.ui.control.UIAxes
        p_c                            matlab.ui.control.UIAxes
        LC_F                           matlab.ui.control.UIAxes
        p_fuman                        matlab.ui.control.UIAxes
        p_loxman                       matlab.ui.control.UIAxes
        p_fu                           matlab.ui.control.UIAxes
        p_lox                          matlab.ui.control.UIAxes
        p_pr                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N_chan = 13;
        N_rows2refresh = 100000;
        CaliApp
        CaliArr
        ChanArr
        FilesArr
        CaliMats
        CaliArrMapped
        CaliMatsMapped
        ch_plots            % list of plots with input channels
        calc_plots          % list of plots that require calculations
        disp_val            % list of display values
    end
    
    properties (Access = public)
        Cali_set = 0;
        Cali_open = 0;
        COM_set = 0;
    end
    
    methods (Access = public)
        
        function PlotLive(app)
            N_sensors = 13;
                                    
            if length(app.CaliArr) ~= N_sensors
                errordlg('Number of Sensors does not match Number of Calibrations','Mismatch')
                return
            end
            
            jj = 1;
            
            TimeArr = zeros(app.N_rows2refresh,1);
            ChanRawData = zeros(app.N_rows2refresh,app.N_chan);
            ChanData = zeros(app.N_rows2refresh,app.N_chan);
            
            s = serialport(app.ControllerInputEditField.Value,9600);
            RawDataBlock = ['0,0,0,0,0,0,0,0,0,0,0,0,0' newline '0,0,0,0,0,0,0,0,0,0,0,0,0' newline '0,0,0,0,0,0,0,0,0,0,0,0,0'];
            while 1
                drawnow
                if strcmp(app.Switch.Value,'Off') || isempty(serialportlist)
                    if isempty(serialportlist)
                        waitfor(errordlg('Serial Port connection lost! You will be promted to save data.','Serial Port Disconnected'))
                    end
                    CalibratedData = horzcat(TimeArr(1:jjj),ChanData(1:jjj,:));
                    RawData = horzcat(TimeArr(1:jjj),ChanRawData(1:jjj,:));
                    app.saveData(CalibratedData,'Calibrated')
                    app.saveData(RawData,'Raw')
                    return
                end
                    
                if s.NumBytesAvailable > 0
                    RawDataBlock = read(s,s.NumBytesAvailable,'char');
                end
                flush(s,"input")
                                
                DataStrs = strtrim(split(RawDataBlock,newline));
                if length(DataStrs) > 1
                    LastDataStr = split(DataStrs(end-1),',');
                else
                    LastDataStr = split(DataStrs(end),',');
                end
            
                CurrTime = str2double(LastDataStr{1})*10^-3;
                
                for k = 2:1:length(DataStrs)-1
                    DataLine = split(DataStrs{k},',');
                    TimeArr(jj) = str2double(DataLine{1})*10^-3;
                    RawStrs = str2double(DataLine);
                    %SumChanStr = sum(RawStrs(1:end-1));
                
                    %if str2double(DataLine(end)) ~= SumChanStr
                    if str2double(DataLine(end)) ~= -42069
                        k = k + 1;
                     else
                        for kk = 2:1:length(DataLine)-1     % only look at middle values (ignore current time and sum)
                            ChanRawData(jj,kk - 1) = str2double(DataLine{kk});
                            ChanData(jj,kk - 1) = ChanRawData(jj,kk - 1);
                            
                            %ChanData(jj,kk - 1) = (ChanRawData(jj,kk - 1)+app.CaliArrMapped(kk-1,2))/app.CaliArrMapped(kk-1,1);
                        end
                        
                    jj = jj + 1;
                    end
                end
                
                jjj = jj - 1;
                
%                 for z = 2:1:jjj
%                     dp_pr_dt(z) = (ChanData(z,app.ChanArr(1)) - ChanData(z-1,app.ChanArr(1)))/(TimeRelArr(z)-TimeRelArr(z-1));
%                     dp_pr_dt(z) = (ChanData(z,app.ChanArr(1)) - ChanData(z-1,app.ChanArr(1)))/(TimeRelArr(z)-TimeRelArr(z-1));
%                     dp_pr_dt(z) = (ChanData(z,app.ChanArr(1)) - ChanData(z-1,app.ChanArr(1)))/(TimeRelArr(z)-TimeRelArr(z-1));
%                 end

                if jjj > 10
                    for d = 1:1:length(app.disp_val)
                        if app.disp_val{d} ~= 0
                            app.disp_val{d}.Value = ChanData(jjj,app.ChanArr(d));
                        end
                    end
                end

                TimeRelArr = TimeArr(1:jjj) - CurrTime;
                zz = find(TimeRelArr > -app.TimeIntervaltoPlotEditField.Value,1,'first');
                
                if isempty(zz)
                    zz = 1;
                end
                
                %% PLOTTING
                for p = 1:1:length(app.ch_plots)
                    if app.ch_plots{p} ~= 0         % channels w/o a plot = 0
                        plot(app.ch_plots{p},TimeRelArr(zz:jjj),ChanData(zz:jjj,app.ChanArr(p)))
                    end
                end
            end
        end
        
        function UpdateCali(app,CaliArr,ChanArr,FilesArr,CaliMats)
            app.CaliArr = CaliArr;
            app.ChanArr = ChanArr;
            app.FilesArr = FilesArr;
            app.CaliMats = CaliMats;

            for i = 1:1:app.N_chan
               app.CaliArrMapped(app.ChanArr(i),:) = app.CaliArr(i,:);
            end
            
            for c = 1:1:2
                for r = 1:1:app.N_chan
                    app.CaliMatsMapped{app.ChanArr(r),c} = app.CaliMats{r,c};
                end
            end
            
            app.Cali_set = 1;
            app.EnterCalibrationValuesButton.BackgroundColor = [0.47,0.67,0.19];
        end
    end
    
    methods (Access = private)
        
        function saveData(app,Data,type)
            waitfor(msgbox(['Saving ' type ' Data']))
            
            FileName = uiputfile('*.csv');
            if FileName ~= 0
                writematrix(Data,FileName)
            else
                answer = questdlg('Are you sure you do not want to save data? All unsaved data will be lost!','Are you sure?','Yes','No','No');
                switch answer
                    case 'Yes'
                        return
                    case 'No'
                        app.saveData(Data,type)
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CaliArr = horzcat(ones(app.N_chan,1),zeros(app.N_chan,1));
            app.CaliArrMapped =  horzcat(ones(app.N_chan,1),zeros(app.N_chan,1));
            app.CaliMatsMapped = cell(13,2);
            app.ChanArr = (1:1:13);
            app.FilesArr = cell(13);
            app.FilesArr(:) = {'lin'};
            app.Cali_open = 0;
            app.ch_plots = {app.p_pr,...
                app.p_lox,...
                app.p_fu,...
                app.p_ro,...
                app.p_loxman,...
                app.p_fuman,...
                app.p_pneu,...
                app.p_c,...
                0,...
                0,...
                0,...
                0,...
                app.LC_F};
            
%             app.ch_plots = {0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 0,...
%                 app.LC_F};

            app.disp_val = {app.p_pr_val;
                    app.p_lox_val;
                    app.p_fu_val;
                    app.p_ro_val;
                    app.p_loxman_val;
                    app.p_fuman_val;
                    0;
                    app.p_c_val;
                    0;
                    0;
                    0;
                    0;
                    app.p_lc_val};
            app.TimeIntervaltoPlotEditFieldValueChanged(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        switch app.Switch.Value
            case 'On'
                if app.Cali_set == 0
                    app.Switch.Value = 'Off';
                    errordlg('You must set calibration values and channels.','No Calibration');
                elseif isempty(app.ControllerInputEditField.Value) || app.COM_set == 0
                    app.Switch.Value = 'Off';
                    errordlg('You must specify a COM channel.','No COM channel');
                elseif app.TimeIntervaltoPlotEditField.Value <= 0
                    app.Switch.Value = 'Off';
                    errordlg('You must set time interval to plot over. Must be greater than 0 seconds','No Plot Time Interval');
                else
                    app.RRLamp.Color = 'green';
                    app.EnterCalibrationValuesButton.Enable = 'off';
                    app.PlotLive;
                end
            case 'Off'
                app.RRLamp.Color = 'red';
                app.EnterCalibrationValuesButton.Enable = 'on';
        end            
        end

        % Button pushed function: EnterCalibrationValuesButton
        function EnterCalibrationValuesButtonPushed(app, event)
            app.EnterCalibrationValuesButton.Enable = 'off';
            
            app.CaliApp = Ares_DAQ_Calibration_2021(app, app.CaliArr, app.ChanArr, app.FilesArr);
        end

        % Value changed function: TimeIntervaltoPlotEditField
        function TimeIntervaltoPlotEditFieldValueChanged(app, event)
            value = app.TimeIntervaltoPlotEditField.Value;
            if value <= 0
                errordlg('Must be greater than 0 seconds','Invalid Interval Input')
            else
                for p = 1:1:length(app.ch_plots)
                    if app.ch_plots{p} ~= 0         % channels w/o a plot = 0
                        xlim(app.ch_plots{p},[-value,0])
                    end
                end
                for p = 1:1:length(app.calc_plots)
                    if app.calc_plots{p} ~= 0
                        xlim(app.calc_plots{p},[-value,0])
                    end
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.Cali_open == 1
                delete(app.CaliApp)
            end
            delete(app)
        end

        % Value changed function: p_pr_loylim
        function p_pr_loylimValueChanged(app, event)
            value = app.p_pr_loylim.Value;
            if value >= app.p_pr_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.p_pr,[value,app.p_pr_hiylim.Value])
            end
        end

        % Value changed function: p_pr_hiylim
        function p_pr_hiylimValueChanged(app, event)
            value = app.p_pr_hiylim.Value;
            if value <= app.p_pr_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.p_pr,[app.p_pr_loylim.Value,value])
            end
        end

        % Value changed function: p_lox_loylim
        function p_lox_loylimValueChanged(app, event)
            value = app.p_lox_loylim.Value;
            if value >= app.p_lox_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.p_lox,[value,app.p_lox_hiylim.Value])
                ylim(app.p_loxman,[value,app.p_lox_hiylim.Value])
            end
        end

        % Value changed function: p_lox_hiylim
        function p_lox_hiylimValueChanged(app, event)
            value = app.p_lox_hiylim.Value;
            if value <= app.p_lox_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.p_lox,[app.p_lox_loylim.Value,value])
                ylim(app.p_loxman,[app.p_lox_loylim.Value,value])
            end
        end

        % Value changed function: p_fu_loylim
        function p_fu_loylimValueChanged(app, event)
            value = app.p_fu_loylim.Value;
            if value >= app.p_fu_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.p_fu,[value,app.p_fu_hiylim.Value])
                ylim(app.p_fuman,[value,app.p_fu_hiylim.Value])
            end
        end

        % Value changed function: p_fu_hiylim
        function p_fu_hiylimValueChanged(app, event)
            value = app.p_fu_hiylim.Value;
            if value <= app.p_fu_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.p_fu,[app.p_fu_loylim.Value,value])
                ylim(app.p_fuman,[app.p_fu_loylim.Value,value])
            end
        end

        % Value changed function: ControllerInputEditField
        function ControllerInputEditFieldValueChanged(app, event)
            value = app.ControllerInputEditField.Value;
            COMlist = serialportlist;
            
            if isempty(COMlist)
                errordlg('Plug in your COM device','No COM devices detected by MATLAB')
            else
                for k = 1:1:length(COMlist)
                    if strcmp(COMlist(k),value)
                        app.COM_set = 1;
                        return
                    end
                end
                errordlg(['Specified COM Channel does not match any available channels' newline 'Try ' COMlist],'Invalid COM Channel')
            end
            
        end

        % Value changed function: p_ro_loylim
        function p_ro_loylimValueChanged(app, event)
            value = app.p_ro_loylim.Value;
            if value >= app.p_ro_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.p_ro,[value,app.p_ro_hiylim.Value])
            end
        end

        % Value changed function: p_ro_hiylim
        function p_ro_hiylimValueChanged(app, event)
            value = app.p_ro_hiylim.Value;
            if value <= app.p_ro_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.p_ro,[app.p_ro_loylim.Value,value])
            end
        end

        % Value changed function: p_c_loylim
        function p_c_loylimValueChanged(app, event)
            value = app.p_c_loylim.Value;
            if value >= app.p_c_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.p_c,[value,app.p_c_hiylim.Value])
            end
        end

        % Value changed function: p_c_hiylim
        function p_c_hiylimValueChanged(app, event)
            value = app.p_c_hiylim.Value;
            if value <= app.p_c_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.p_c,[app.p_c_loylim.Value,value])
            end
        end

        % Value changed function: p_lc_loylim
        function p_lc_loylimValueChanged(app, event)
            value = app.p_lc_loylim.Value;
            if value >= app.p_lc_hiylim.Value
                errordlg('Lower y limit must be less than upper y limit','Invalid lower y limit')
            else
                ylim(app.LC_F,[value,app.p_lc_hiylim.Value])
            end
        end

        % Value changed function: p_lc_hiylim
        function p_lc_hiylimValueChanged(app, event)
            value = app.p_lc_hiylim.Value;
            if value <= app.p_lc_loylim.Value
                errordlg('Upper y limit must be greater than lower y limit','Invalid upper y limit')
            else
                ylim(app.LC_F,[app.p_lc_loylim.Value,value])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.p_pr = uiaxes(app.UIFigure);
            title(app.p_pr, 'Pressurant Tank')
            xlabel(app.p_pr, 'Time (s)')
            ylabel(app.p_pr, 'Pressure (psig)')
            app.p_lox = uiaxes(app.UIFigure);
            title(app.p_lox, 'LOX Tank')
            xlabel(app.p_lox, 'Time (s)')
            ylabel(app.p_lox, 'Pressure (psig)')
            app.p_fu = uiaxes(app.UIFigure);
            title(app.p_fu, 'Fuel Tank')
            xlabel(app.p_fu, 'Time (s)')
            ylabel(app.p_fu, 'Pressure (psig)')
            app.p_loxman = uiaxes(app.UIFigure);
            title(app.p_loxman, 'LOX Manifold')
            xlabel(app.p_loxman, 'Time (s)')
            ylabel(app.p_loxman, 'Pressure (psig)')
            app.p_fuman = uiaxes(app.UIFigure);
            title(app.p_fuman, 'Fuel Manifold')
            xlabel(app.p_fuman, 'Time (s)')
            ylabel(app.p_fuman, 'Pressure (psig)')
            app.LC_F = uiaxes(app.UIFigure);
            title(app.LC_F, 'Load Cell')
            xlabel(app.LC_F, 'Time(s)')
            ylabel(app.LC_F, 'Force (lbf)')
            app.p_c = uiaxes(app.UIFigure);
            title(app.p_c, 'Chamber Pressure')
            xlabel(app.p_c, 'Time (s)')
            ylabel(app.p_c, 'Pressure (psig)')
            app.p_pneu = uiaxes(app.UIFigure);
            title(app.p_pneu, 'Pneumatics')
            xlabel(app.p_pneu, 'X')
            ylabel(app.p_pneu, 'Y')
            app.p_ro = uiaxes(app.UIFigure);
            title(app.p_ro, 'Regulator Outlet')
            xlabel(app.p_ro, 'Time (s)')
            ylabel(app.p_ro, 'Pressure (psig)')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RunningandRecordingLabel = uilabel(app.UIFigure);
            app.RunningandRecordingLabel.Text = 'Running and Recording:';
            app.RRLamp = uilamp(app.UIFigure);
            app.EnterCalibrationValuesButton = uibutton(app.UIFigure, 'push');
            app.EnterCalibrationValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EnterCalibrationValuesButtonPushed, true);
            app.EnterCalibrationValuesButton.Text = 'Enter Calibration Values';
            app.TimeIntervaltoPlotEditFieldLabel = uilabel(app.UIFigure);
            app.TimeIntervaltoPlotEditFieldLabel.Text = 'Time Interval to Plot';
            app.TimeIntervaltoPlotEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeIntervaltoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervaltoPlotEditFieldValueChanged, true);
            app.TimeIntervaltoPlotEditField.Value = 20;
            app.secLabel = uilabel(app.UIFigure);
            app.secLabel.Text = 'sec';
            app.p_pr_val = uieditfield(app.UIFigure, 'numeric');
            app.p_pr_val.ValueDisplayFormat = '%.2f';
            app.p_lox_val = uieditfield(app.UIFigure, 'numeric');
            app.p_lox_val.ValueDisplayFormat = '%.2f';
            app.p_fu_val = uieditfield(app.UIFigure, 'numeric');
            app.p_fu_val.ValueDisplayFormat = '%.2f';
            app.p_pr_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_pr_loylim.ValueChangedFcn = createCallbackFcn(app, @p_pr_loylimValueChanged, true);
            app.ylimitsLabel = uilabel(app.UIFigure);
            app.ylimitsLabel.Text = 'y limits:';
            app.p_pr_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_pr_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_pr_hiylimValueChanged, true);
            app.p_lox_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_lox_loylim.ValueChangedFcn = createCallbackFcn(app, @p_lox_loylimValueChanged, true);
            app.ylimitsLabel_2 = uilabel(app.UIFigure);
            app.ylimitsLabel_2.Text = 'y limits:';
            app.p_lox_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_lox_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_lox_hiylimValueChanged, true);
            app.p_fu_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_fu_loylim.ValueChangedFcn = createCallbackFcn(app, @p_fu_loylimValueChanged, true);
            app.ylimitsLabel_3 = uilabel(app.UIFigure);
            app.ylimitsLabel_3.Text = 'y limits:';
            app.p_fu_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_fu_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_fu_hiylimValueChanged, true);
            app.ControllerInputEditFieldLabel = uilabel(app.UIFigure);
            app.ControllerInputEditFieldLabel.Text = 'Controller Input';
            app.ControllerInputEditField = uieditfield(app.UIFigure, 'text');
            app.ControllerInputEditField.ValueChangedFcn = createCallbackFcn(app, @ControllerInputEditFieldValueChanged, true);
            app.ControllerInputEditField.Value = 'COM';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'small logo.png';
            app.ProjectAres20202021PropulsionSystemDAQLabel = uilabel(app.UIFigure);
            app.ProjectAres20202021PropulsionSystemDAQLabel.Text = 'Project Ares 2020 - 2021 Propulsion System DAQ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.RefreshLamp = uilamp(app.UIFigure);
            app.p_ro_val = uieditfield(app.UIFigure, 'numeric');
            app.p_ro_val.ValueDisplayFormat = '%.2f';
            app.p_ro_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_ro_loylim.ValueChangedFcn = createCallbackFcn(app, @p_ro_loylimValueChanged, true);
            app.ylimitsLabel_4 = uilabel(app.UIFigure);
            app.ylimitsLabel_4.Text = 'y limits:';
            app.p_ro_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_ro_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_ro_hiylimValueChanged, true);
            app.p_lc_val = uieditfield(app.UIFigure, 'numeric');
            app.p_lc_val.ValueDisplayFormat = '%.2f';
            app.p_c_val = uieditfield(app.UIFigure, 'numeric');
            app.p_c_val.ValueDisplayFormat = '%.2f';
            app.p_loxman_val = uieditfield(app.UIFigure, 'numeric');
            app.p_loxman_val.ValueDisplayFormat = '%.2f';
            app.p_fuman_val = uieditfield(app.UIFigure, 'numeric');
            app.p_fuman_val.ValueDisplayFormat = '%.2f';
            app.p_c_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_c_loylim.ValueChangedFcn = createCallbackFcn(app, @p_c_loylimValueChanged, true);
            app.ChamberPressureylimitsLabel = uilabel(app.UIFigure);
            app.ChamberPressureylimitsLabel.Text = 'Chamber Pressure y limits:';
            app.p_c_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_c_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_c_hiylimValueChanged, true);
            app.p_lc_loylim = uieditfield(app.UIFigure, 'numeric');
            app.p_lc_loylim.ValueChangedFcn = createCallbackFcn(app, @p_lc_loylimValueChanged, true);
            app.LoadCellylimitsLabel = uilabel(app.UIFigure);
            app.LoadCellylimitsLabel.Text = 'Load Cell y limits:';
            app.p_lc_hiylim = uieditfield(app.UIFigure, 'numeric');
            app.p_lc_hiylim.ValueChangedFcn = createCallbackFcn(app, @p_lc_hiylimValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ares_DAQ_GUI_2021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end