classdef prometheusLaunchDAQgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DAQGUILabel              matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        LAUNCHLabel              matlab.ui.control.Label
        PROMETHEUSLabel          matlab.ui.control.Label
        PT1text                  matlab.ui.control.TextArea
        PRESSURETRANSDUCERLabel  matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        PT1Plot                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStart(app)

            % Data comes in as 
            
            jj = 1;
            
            % CHANGE THIS LINE ACCORDING TO WHAT PORT YOUR ARDUINO IS AT
            s = serialport("/dev/cu.usbmodem142101", 9600);
            ylim(app.PT1Plot,[0,1000]) % limit what comes in on the y axis
            ChanRawData = [];
            ChanData = [];
            
            % CHANGE THE NAME OF THE .TXT EACH TIME YOU RUN THE GUI, TO
            % CREATE A NEW FILE
            fileID = fopen("randomLaunchName.txt", 'W');
           
            TimeArr = [];
            ChanRawData = [];
            ChanData = [];
            RawDataBlock = ['0,0,0,0,0,0,0,0,0,0,0,0,0' newline '0,0,0,0,0,0,0,0,0,0,0,0,0' newline '0,0,0,0,0,0,0,0,0,0,0,0,0'];
            
            while app.Switch.Value == 'On'
                
                drawnow
                if s.NumBytesAvailable > 0
                    RawDataBlock = read(s,s.NumBytesAvailable,'char');
                    fprintf(fileID, RawDataBlock);

                end
                flush(s,"input")
                
                DataStrs = strtrim(split(RawDataBlock,newline));
                if length(DataStrs) > 1
                    LastDataStr = split(DataStrs(end-1),',');
                else
                    LastDataStr = split(DataStrs(end),',');
                end
            
                CurrTime = str2double(LastDataStr{1})*10^-3;
                
                for k = 2:1:length(DataStrs)
                    
                    DataLine = split(DataStrs{k},',');
                    
                    % Printing the PT, LC values in text boxes
                    try 
                        app.PT1text.Value = DataLine{2}
                    catch ME
                        continue;
                    end
        
                    TimeArr(jj) = str2double(DataLine{1})*10^-3;
                    RawStrs = str2double(DataLine);

                    if str2double(DataLine(end)) ~= -42069
                        k = k + 1;
                    else
                        for kk = 2:1:length(DataLine)-1     % only look at middle values (ignore current time and sum)
                            ChanRawData(jj,kk - 1) = str2double(DataLine{kk});
                            ChanData(jj,kk - 1) = ChanRawData(jj,kk - 1);
                        end
                    jj = jj + 1;
                    end 
                    
                end
                jjj = jj - 1;
                
                TimeRelArr = TimeArr(1:jjj) - CurrTime;
                zz = find(TimeRelArr > -600,1,'first');      % seconds to plot over, rn 30
                
                if isempty(zz)
                    zz = 1;
                end
                
                try 
                    plot(app.PT1Plot, TimeRelArr(zz:jjj), ChanData(zz:jjj,1))     % change 1 to other channel numbers
                catch ME
                    continue;
                end
               
            end
        end

        % Value changed function: Switch
        function switchValue(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PT1Plot = uiaxes(app.UIFigure);
            title(app.PT1Plot, 'PT1')
            xlabel(app.PT1Plot, 'X')
            ylabel(app.PT1Plot, 'Y')
            zlabel(app.PT1Plot, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @switchValue, true);
            app.Switch.Value = 'On';
            app.PRESSURETRANSDUCERLabel = uilabel(app.UIFigure);
            app.PRESSURETRANSDUCERLabel.Text = 'PRESSURE TRANSDUCER';
            app.PT1text = uitextarea(app.UIFigure);
            app.PROMETHEUSLabel = uilabel(app.UIFigure);
            app.PROMETHEUSLabel.Text = 'PROMETHEUS ';
            app.LAUNCHLabel = uilabel(app.UIFigure);
            app.LAUNCHLabel.Text = 'LAUNCH';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.DAQGUILabel = uilabel(app.UIFigure);
            app.DAQGUILabel.Text = 'DAQ GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prometheusLaunchDAQgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end